classdef Exam_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Exam1UIFigure             matlab.ui.Figure
        ServerResponsePanel       matlab.ui.container.Panel
        Response                  matlab.ui.control.TextArea
        LEDFlashControlsPanel     matlab.ui.container.Panel
        FlashButton               matlab.ui.control.Button
        PeriodmsEditField         matlab.ui.control.NumericEditField
        PeriodmsEditFieldLabel    matlab.ui.control.Label
        NumFlashesEditField       matlab.ui.control.NumericEditField
        NumFlashesEditFieldLabel  matlab.ui.control.Label
        ManualLEDControlsPanel    matlab.ui.container.Panel
        LEDOffButton              matlab.ui.control.Button
        LEDOnButton               matlab.ui.control.Button
    end


    methods (Access = private)

        function response = sendCommand(~, command)
            import matlab.net.*
            import matlab.net.http.*

            r = RequestMessage;
            uri = URI("http://sinclaer-car.rose-hulman.edu:8080/api/" + command);
            resp = send(r,uri);
            response = resp.Body.Data;

            fprintf("Response to %s --> %s \n", command, response);

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.Response.Value = "Responses will appear here";
        end

        % Button pushed function: LEDOnButton
        function LEDOnButtonPushed(app, event)
            command = 'LED ON';
            response = app.sendCommand(command);
            app.Response.Value = response;
        end

        % Button pushed function: LEDOffButton
        function LEDOffButtonPushed(app, event)
            command = 'LED OFF';
            response = app.sendCommand(command);
            app.Response.Value = response;
        end

        % Button pushed function: FlashButton
        function FlashButtonPushed(app, event)
            numflash = app.NumFlashesEditField.Value;
            periodms = app.PeriodmsEditField.Value;
            command = sprintf("FLASH " + num2str(numflash) + " " + num2str(periodms));
            response = app.sendCommand(command);
            app.Response.Value = response;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Exam1UIFigure = uifigure('Visible', 'off');
            app.Exam1UIFigure.Name = 'Exam 1';
            app.ManualLEDControlsPanel = uipanel(app.Exam1UIFigure);
            app.ManualLEDControlsPanel.Title = 'Manual LED Controls';
            app.LEDOnButton = uibutton(app.ManualLEDControlsPanel, 'push');
            app.LEDOnButton.ButtonPushedFcn = createCallbackFcn(app, @LEDOnButtonPushed, true);
            app.LEDOnButton.Text = 'LED On';
            app.LEDOffButton = uibutton(app.ManualLEDControlsPanel, 'push');
            app.LEDOffButton.ButtonPushedFcn = createCallbackFcn(app, @LEDOffButtonPushed, true);
            app.LEDOffButton.Text = 'LED Off';
            app.LEDFlashControlsPanel = uipanel(app.Exam1UIFigure);
            app.LEDFlashControlsPanel.Title = 'LED Flash Controls';
            app.NumFlashesEditFieldLabel = uilabel(app.LEDFlashControlsPanel);
            app.NumFlashesEditFieldLabel.Text = 'Num Flashes';
            app.NumFlashesEditField = uieditfield(app.LEDFlashControlsPanel, 'numeric');
            app.PeriodmsEditFieldLabel = uilabel(app.LEDFlashControlsPanel);
            app.PeriodmsEditFieldLabel.Text = 'Period (ms)';
            app.PeriodmsEditField = uieditfield(app.LEDFlashControlsPanel, 'numeric');
            app.FlashButton = uibutton(app.LEDFlashControlsPanel, 'push');
            app.FlashButton.ButtonPushedFcn = createCallbackFcn(app, @FlashButtonPushed, true);
            app.FlashButton.Text = 'Flash';
            app.ServerResponsePanel = uipanel(app.Exam1UIFigure);
            app.ServerResponsePanel.Title = 'Server Response';
            app.Response = uitextarea(app.ServerResponsePanel);
            % Show the figure after all components are created
            app.Exam1UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Exam_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Exam1UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Exam1UIFigure)
        end
    end
end