classdef FortuneTellerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Image_2           matlab.ui.control.Image
        Image             matlab.ui.control.Image
        StatusLabel       matlab.ui.control.Label
        GetFortuneButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        mqClient % Description
    end

    methods (Access = private)
        
        function myCallback(app, topic, message)
            fprintf("Recieved: %s\n",message);
            messageStruct = jsondecode(message);
            messageType = messageStruct.type;

            if strcmp(messageType, "get_fortune")
                messagePayload = messageStruct.payload;
            end

             if strcmp(messageType, "payment")
                 messagePayload = messageStruct.payload;
                 if messagePayload
                    app.StatusLabel.Text = sprintf("Payment Recieved");
                    pause(2)
                    app.StatusLabel.Text = sprintf("Tap Button");
                 else
                     app.StatusLabel.Text = sprintf("Payment Invalid, Try Again");
                 end
             end

             if strcmp(messageType, "reset")
                 app.StatusLabel.Text = sprintf("Scan Card");
             end

        end

        function sendMessage(app, messageType, messagePayload)
            messageStruct.type = messageType;

            if nargin > 2
                messageStruct.payload = messagePayload;
            end

            messageJson = jsonencode(messageStruct);
            write(app.mqClient, "me435/daffrojp/to_pi", messageJson);
            fprintf("Sent: %s\n", messageJson);
        end

     
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");
            app.mqClient.subscribe("me435/daffrojp/#", "Callback", @app.myCallback)
        end

        % Button pushed function: GetFortuneButton
        function GetFortuneButtonPushed(app, event)
            app.sendMessage("button", true);
            for i=1:5
                app.StatusLabel.Text = sprintf("Thinking");
                pause(0.25)
                app.StatusLabel.Text = sprintf("Thinking.");
                pause(0.25)
                app.StatusLabel.Text = sprintf("Thinking..");
                pause(0.25)
                app.StatusLabel.Text = sprintf("Thinking...");
                pause(0.25)
            end
            app.StatusLabel.Text = sprintf("See LCD screen for fortune!");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GetFortuneButton = uibutton(app.UIFigure, 'push');
            app.GetFortuneButton.ButtonPushedFcn = createCallbackFcn(app, @GetFortuneButtonPushed, true);
            app.GetFortuneButton.Text = 'Get Fortune';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status: Scan Card';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'The_Genie_Aladdin.png';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = 'The_Genie_Aladdin.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FortuneTellerGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end