classdef Exam3MQTT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Exam3MQTTUIFigure   matlab.ui.Figure
        Distance0cmLabel    matlab.ui.control.Label
        BothReverseButton   matlab.ui.control.Button
        BothForwardButton   matlab.ui.control.Button
        RightReverseButton  matlab.ui.control.Button
        RightForwardButton  matlab.ui.control.Button
        LeftReverseButton   matlab.ui.control.Button
        LeftForwardButton   matlab.ui.control.Button
        RightSlider         matlab.ui.control.Slider
        RightSliderLabel    matlab.ui.control.Label
        LeftSlider          matlab.ui.control.Slider
        LeftSliderLabel     matlab.ui.control.Label
        TimeSlider          matlab.ui.control.Slider
        TimeSliderLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        mqClient
    end

    methods (Access = private)

        function myCallback(app, topic, message)
            messageStruct = jsondecode(message);
            messageType = messageStruct.type;

            % Checking to see if there is a payload:
            if strcmp(messageType, "set_speeds")
                messagePayload = messageStruct.payload;
            end

            if strcmp(messageType, "ultra")
                messagePayload = messageStruct.payload;
                app.Distance0cmLabel.Text = sprintf("Distance = %3.0f cm \n", round(messagePayload, 0));
            end

        end

        function sendMessage(app, messageType, messagePayload)
            messageStruct.type = messageType;
            if nargin > 2
                messageStruct.payload = messagePayload;
            end
            messageJson = jsonencode(messageStruct);

            % both write methods work
            % app.mqClient.write("chat", messageJson);
            write(app.mqClient, "me435/daffrojp/to_pi", messageJson);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            % If off campus:
            % app.mqClient = mqttclient("tcp://broker.hivemq.com");
            % On Campus, subscribed to me
            app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");
            app.mqClient.subscribe("me435/daffrojp/#", "Callback", @app.myCallback);
        end

        % Button pushed function: LeftForwardButton
        function LeftForwardButtonPushed(app, event)
            app.sendMessage("left", ...
                [1, app.TimeSlider.Value, app.LeftSlider.Value])
            fprintf("Side: Left, Direction: 1, Time: %3.1f, Speed: " + ...
                "%3.0f\n", app.TimeSlider.Value, app.LeftSlider.Value);
        end

        % Button pushed function: LeftReverseButton
        function LeftReverseButtonPushed(app, event)
            app.sendMessage("left", ...
                [0, app.TimeSlider.Value, app.LeftSlider.Value])
            fprintf("Side: Left, Direction: 0, Time: %3.1f, Speed: " + ...
                "%3.0f\n", app.TimeSlider.Value, app.LeftSlider.Value);
        end

        % Button pushed function: RightForwardButton
        function RightForwardButtonPushed(app, event)
            app.sendMessage("right", ...
                [1, app.TimeSlider.Value, app.RightSlider.Value])
            fprintf("Side: Right, Direction: 1, Time: %3.1f, Speed: " + ...
                "%3.0f\n", app.TimeSlider.Value, app.RightSlider.Value);
        end

        % Button pushed function: RightReverseButton
        function RightReverseButtonPushed(app, event)
            app.sendMessage("right", ...
                [0, app.TimeSlider.Value, app.RightSlider.Value])
            fprintf("Side: Right, Direction: 0, Time: %3.1f, Speed: " + ...
                "%3.0f\n", app.TimeSlider.Value, app.RightSlider.Value);
        end

        % Button pushed function: BothForwardButton
        function BothForwardButtonPushed(app, event)
            app.sendMessage("both", ...
                [1, app.TimeSlider.Value, app.LeftSlider.Value, app.RightSlider.Value])
            fprintf("Side: Both, Direction: 1, Time: %3.1f, Speed " + ...
                "Left: %3.0f, Speed Right: %3.0f \n",app.TimeSlider.Value ...
                , app.LeftSlider.Value, app.RightSlider.Value);
        end

        % Button pushed function: BothReverseButton
        function BothReverseButtonPushed(app, event)
            app.sendMessage("both", ...
                [0, app.TimeSlider.Value, app.LeftSlider.Value, app.RightSlider.Value])
            fprintf("Side: Both, Direction: 0, Time: %3.1f, Speed " + ...
                "Left: %3.0f, Speed Right: %3.0f \n",app.TimeSlider.Value ...
                , app.LeftSlider.Value, app.RightSlider.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Exam3MQTTUIFigure = uifigure('Visible', 'off');
            app.Exam3MQTTUIFigure.Name = 'Exam 3 MQTT';
            app.TimeSliderLabel = uilabel(app.Exam3MQTTUIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.Exam3MQTTUIFigure);
            app.TimeSlider.Limits = [0.5 4];
            app.TimeSlider.Value = 1;
            app.LeftSliderLabel = uilabel(app.Exam3MQTTUIFigure);
            app.LeftSliderLabel.Text = 'Left';
            app.LeftSlider = uislider(app.Exam3MQTTUIFigure);
            app.LeftSlider.Value = 40;
            app.RightSliderLabel = uilabel(app.Exam3MQTTUIFigure);
            app.RightSliderLabel.Text = 'Right';
            app.RightSlider = uislider(app.Exam3MQTTUIFigure);
            app.RightSlider.Value = 40;
            app.LeftForwardButton = uibutton(app.Exam3MQTTUIFigure, 'push');
            app.LeftForwardButton.ButtonPushedFcn = createCallbackFcn(app, @LeftForwardButtonPushed, true);
            app.LeftForwardButton.Text = 'Left Forward';
            app.LeftReverseButton = uibutton(app.Exam3MQTTUIFigure, 'push');
            app.LeftReverseButton.ButtonPushedFcn = createCallbackFcn(app, @LeftReverseButtonPushed, true);
            app.LeftReverseButton.Text = 'Left Reverse';
            app.RightForwardButton = uibutton(app.Exam3MQTTUIFigure, 'push');
            app.RightForwardButton.ButtonPushedFcn = createCallbackFcn(app, @RightForwardButtonPushed, true);
            app.RightForwardButton.Text = 'Right Forward';
            app.RightReverseButton = uibutton(app.Exam3MQTTUIFigure, 'push');
            app.RightReverseButton.ButtonPushedFcn = createCallbackFcn(app, @RightReverseButtonPushed, true);
            app.RightReverseButton.Text = 'Right Reverse';
            app.BothForwardButton = uibutton(app.Exam3MQTTUIFigure, 'push');
            app.BothForwardButton.ButtonPushedFcn = createCallbackFcn(app, @BothForwardButtonPushed, true);
            app.BothForwardButton.Text = 'Both Forward';
            app.BothReverseButton = uibutton(app.Exam3MQTTUIFigure, 'push');
            app.BothReverseButton.ButtonPushedFcn = createCallbackFcn(app, @BothReverseButtonPushed, true);
            app.BothReverseButton.Text = 'Both Reverse';
            app.Distance0cmLabel = uilabel(app.Exam3MQTTUIFigure);
            app.Distance0cmLabel.Text = 'Distance = 0 cm';
            % Show the figure after all components are created
            app.Exam3MQTTUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Exam3MQTT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Exam3MQTTUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Exam3MQTTUIFigure)
        end
    end
end